classdef simulinkModell_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        OutputsfromSimulinkModelPanel  matlab.ui.container.Panel
        out1EditFieldLabel             matlab.ui.control.Label
        Out1                           matlab.ui.control.NumericEditField
        out2EditFieldLabel             matlab.ui.control.Label
        Out2                           matlab.ui.control.NumericEditField
        InputstoSimulinkModelPanel     matlab.ui.container.Panel
        gainValueEditFieldLabel        matlab.ui.control.Label
        edit_gainValue                 matlab.ui.control.NumericEditField
        constantValueEditFieldLabel    matlab.ui.control.Label
        edit_constantValue             matlab.ui.control.NumericEditField
        ExampleshowingtheInterfacebetweenAppSimulinkModelLabel  matlab.ui.control.Label
        RunButton                      matlab.ui.control.Button
        SelectSimulinkModelPanel       matlab.ui.container.Panel
        edit_SelectedMDLFile           matlab.ui.control.EditField
        selectSimulinkModel            matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupfcn(app)
            movegui(app.UIFigure,'center');
        end

        % Button pushed function: RunButton
        function runSimulation(app, event)
            app.Out1.Tag = 'Out1';
            app.Out2.Tag = 'Out2';
            app.UIAxes.Tag = 'UIAxes';
            cla(app.UIAxes);
            % assigning variables to workspace
            assignin('base','gainVal',app.edit_gainValue.Value);
            assignin('base','constantVal',app.edit_constantValue.Value);
            simulinkModel = app.edit_SelectedMDLFile.Value;
            simulinkModel = extractBefore(simulinkModel,'.');
            disp('########## Opening Simulink-Modell ##########');
            fprintf('Simulink-Modell: %s.mdl\n', simulinkModel)
            open_system(simulinkModel);
            set_param(simulinkModel,'SimulationCommand','start');
        end

        % Button pushed function: selectSimulinkModel
        function selectSimulinkModelButtonPushed(app, event)
            persistent Directory
            [File, Directory2] = uigetfile({'*.slx','Simulink Modell (*.slx)';'*.*','alle Dateien (*.*)'},'Wählen Sie eine relevante Simulink Modell',Directory);
            drawnow;
            figure(app.UIFigure);
            if ~ischar(File)
                return
            else
                Directory = Directory2;
            end                     
            set(app.edit_SelectedMDLFile,'Value',File);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.OutputsfromSimulinkModelPanel = uipanel(app.UIFigure);
            app.OutputsfromSimulinkModelPanel.Title = 'Outputs from Simulink Model';
            app.out1EditFieldLabel = uilabel(app.OutputsfromSimulinkModelPanel);
            app.out1EditFieldLabel.Text = 'out1';
            app.Out1 = uieditfield(app.OutputsfromSimulinkModelPanel, 'numeric');
            app.out2EditFieldLabel = uilabel(app.OutputsfromSimulinkModelPanel);
            app.out2EditFieldLabel.Text = 'out2';
            app.Out2 = uieditfield(app.OutputsfromSimulinkModelPanel, 'numeric');
            app.InputstoSimulinkModelPanel = uipanel(app.UIFigure);
            app.InputstoSimulinkModelPanel.Title = 'Inputs to Simulink Model';
            app.gainValueEditFieldLabel = uilabel(app.InputstoSimulinkModelPanel);
            app.gainValueEditFieldLabel.Text = 'gain Value';
            app.edit_gainValue = uieditfield(app.InputstoSimulinkModelPanel, 'numeric');
            app.constantValueEditFieldLabel = uilabel(app.InputstoSimulinkModelPanel);
            app.constantValueEditFieldLabel.Text = 'constant Value';
            app.edit_constantValue = uieditfield(app.InputstoSimulinkModelPanel, 'numeric');
            app.ExampleshowingtheInterfacebetweenAppSimulinkModelLabel = uilabel(app.UIFigure);
            app.ExampleshowingtheInterfacebetweenAppSimulinkModelLabel.Text = 'Example showing the Interface between App & Simulink Model ';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @runSimulation, true);
            app.RunButton.Text = 'Run';
            app.SelectSimulinkModelPanel = uipanel(app.UIFigure);
            app.SelectSimulinkModelPanel.Title = 'Select Simulink Model';
            app.edit_SelectedMDLFile = uieditfield(app.SelectSimulinkModelPanel, 'text');
            app.selectSimulinkModel = uibutton(app.SelectSimulinkModelPanel, 'push');
            app.selectSimulinkModel.ButtonPushedFcn = createCallbackFcn(app, @selectSimulinkModelButtonPushed, true);
            app.selectSimulinkModel.Text = '...';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Plot of out1 over time')
            xlabel(app.UIAxes, 'time (s)')
            ylabel(app.UIAxes, 'out1')
        end
    end

    methods (Access = public)

        % Construct app
        function app = simulinkModell_App

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupfcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end